classdef Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        btn_OpenHelp                   matlab.ui.control.Button
        KoflocControllerGUILabel       matlab.ui.control.Label
        TabGroupLeft                   matlab.ui.container.TabGroup
        tb_setup                       matlab.ui.container.Tab
        ManualExplainModes_2           matlab.ui.control.Button
        btn_disconnect                 matlab.ui.control.Button
        txt_valves                     matlab.ui.control.EditField
        ValuesLabel                    matlab.ui.control.Label
        ConnectedLamp                  matlab.ui.control.Lamp
        ConnectedLampLabel             matlab.ui.control.Label
        txt_runname                    matlab.ui.control.EditField
        RunnameLabel                   matlab.ui.control.Label
        EstimatedtotaltimeLabel        matlab.ui.control.Label
        txt_time_estimtotal            matlab.ui.control.Label
        na_baud                        matlab.ui.control.NumericEditField
        BaudrateLabel                  matlab.ui.control.Label
        txa_port                       matlab.ui.control.EditField
        PortLabel                      matlab.ui.control.Label
        btn_connect                    matlab.ui.control.Button
        ManualcontrolTab               matlab.ui.container.Tab
        lastchecktimeLabel             matlab.ui.control.Label
        lastcheckedLabel               matlab.ui.control.Label
        ValveSPLabel                   matlab.ui.control.Label
        SetPointLabel                  matlab.ui.control.Label
        ValveCheckButton               matlab.ui.control.Button
        ValveStatusLabel               matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        ManualExplainModes             matlab.ui.control.Button
        OperationincontrolmodeLabel    matlab.ui.control.Label
        OperationmodeLabel             matlab.ui.control.Label
        ManualControl                  matlab.ui.control.Button
        ManualSetFlow                  matlab.ui.control.Button
        ManualFlowNumber               matlab.ui.control.NumericEditField
        NewflowsetpointEditFieldLabel  matlab.ui.control.Label
        ManualClose                    matlab.ui.control.Button
        ManualOpen                     matlab.ui.control.Button
        ValveNumberDropDown            matlab.ui.control.DropDown
        ValvenumberDropDownLabel       matlab.ui.control.Label
        tb_runtime                     matlab.ui.container.Tab
        ElapsedtimestepLabel           matlab.ui.control.Label
        txt_time_elapsed_step          matlab.ui.control.Label
        gauge_time                     matlab.ui.control.LinearGauge
        totalLabel                     matlab.ui.control.Label
        txt_time_elapsed               matlab.ui.control.Label
        PausedtimeLabel                matlab.ui.control.Label
        txt_time_paused                matlab.ui.control.Label
        EndtimeLabel                   matlab.ui.control.Label
        txt_time_end                   matlab.ui.control.Label
        txt_time_start                 matlab.ui.control.Label
        StarttimeLabel                 matlab.ui.control.Label
        txt_status                     matlab.ui.control.Label
        OpenButton                     matlab.ui.control.Button
        btn_pause                      matlab.ui.control.Button
        txt_scriptfile_name            matlab.ui.control.EditField
        StartLabel_4                   matlab.ui.control.Label
        txt_logfile_name               matlab.ui.control.EditField
        StartLabel_3                   matlab.ui.control.Label
        running_lamp                   matlab.ui.control.Lamp
        StartButton                    matlab.ui.control.Button
        EndrunButton                   matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        btn_Version                    matlab.ui.control.Button
        ARSAGG0423V21Label             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        next_steps                     matlab.ui.container.Tab
        btn_NextStepLoad               matlab.ui.control.Button
        NextLabel                      matlab.ui.control.Label
        CurrentLabel                   matlab.ui.control.Label
        ta_CurrentStep                 matlab.ui.control.TextArea
        btn_EndCurrentStep             matlab.ui.control.Button
        ta_NextSteps                   matlab.ui.control.TextArea
        btn_NextStepEdit               matlab.ui.control.Button
        prev_steps                     matlab.ui.container.Tab
        CheckthedetailsandmanualchangesnotshownhereinthelogfileLabel  matlab.ui.control.Label
        ta_PrevSteps                   matlab.ui.control.TextArea
        AddlognotesTab                 matlab.ui.container.Tab
        btn_lognote_add                matlab.ui.control.Button
        cb_lognote_addtime             matlab.ui.control.CheckBox
        txa_lognote                    matlab.ui.control.TextArea
        CommentTextAreaLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        % ----------------------------------------------------------------
        % Close the port if it was open, before doing something else
        function ClosePort(app, puerto, logto)
            % Check existing serial objects with 'Port' property
            oldSerial = instrfind('Port', puerto); 
            % instrfind() would find ALL existing
            serial objects;
            % if the set of such objects is not(~) empty
            if (~isempty(oldSerial))
                disp('Selected COM in use. Closing.')
                delete(oldSerial);
            end
            pause(0.07); % wait at least 50ms for serial com
            % log if file was given
            if exist('logto','var')
                fprintf(fopen(logto,'a'), ...
                    "    >ClosePort (" + datestr(now)+ "):" + ...
                    " Closed port " + puerto + "\r\n");
            end
        end
        
        % ----------------------------------------------------------------
        % Open the serial port to start streaming data.
        function device=OpenPort(app, puerto,baud, logto)
            device  = serial(puerto, ...
                'baudrate', baud, ...
                'terminator', 'CR') ;
            fopen(device) ;
            pause(0.07);% wait at least 50ms for serial com
            % log if file was given
            if exist('logto','var')
                fprintf(fopen(logto,'a'), "    >OpenPort     (" + ...
                    datestr(now)+ "):" + " Opening port " + puerto + ...
                    ", baudrate=" + baud + "\r\n");
            end
        end

        % ----------------------------------------------------------------
        % Function to close the valve. this function must be called after 
        % a batch to make sure not gas is wasted. This anulates the 
        % setpoint until ValveControl is called.
        function ValveClose(app, number,device, logto)
            if number<10
                A=['@0' ,int2str(number), 'WCVCLSE'];
            else
                A=['@' ,int2str(number), 'WCVCLSE'];
            end
            hexStr = compose("%X",A);
            Suma=hex2dec(hexStr);
            Suma=sum(Suma);
            Checksum=bitand(Suma,255);
            Checksum=dec2hex(Checksum);
            C=[A Checksum];
            fprintf(device,C);
            pause(0.07);% wait at least 50ms for serial com
            % log if file was given
            if exist('logto','var')
                fprintf(fopen(logto,'a'), "    >ValveClose   (" + ...
                    datestr(now)+ "): closed valve #" + number + "\r\n");
            end
        end

        % ----------------------------------------------------------------
        % Function to set the valve to control mode. This function must 
        % have been called for the valve to use its set point.
        % If ValveOpen or ValveClose were used, set point will not work 
        % until control mode is activated again with ValveControl
        function C=ValveControl(app, number,device, logto)
            if number<10
                A=['@0' ,int2str(number), 'WCVCNTL'];
            else
                A=['@' ,int2str(number), 'WCVCNTL'];
            end
            hexStr = compose("%X",A);
            Suma=hex2dec(hexStr);
            Suma=sum(Suma);
            Checksum=bitand(Suma,255);
            Checksum=dec2hex(Checksum);
            C=[A Checksum];
            fprintf(device,C);
            pause(0.07);% wait at least 50ms for serial com
            % log if file was given
            if exist('logto','var')
                fprintf(fopen(logto,'a'), "    >ValveControl    (" + ...
                    datestr(now) + "): set control mode for valve #" + ...
                    number  + "\r\n");
            end
        end

        % ----------------------------------------------------------------
        % Function to open the valve. This will set the valve mode to 
        % open and anulates the setpoint until ValveControl is called 
        % to set the valve to control mode again.
        function ValveOpen(app, number,device, logto)
            if number<10
                A=['@0' ,int2str(number), 'WCVOPEN'];
            else
                A=['@' ,int2str(number), 'WCVOPEN'];
            end
            hexStr = compose("%X",A);
            Suma=hex2dec(hexStr);
            Suma=sum(Suma);
            Checksum=bitand(Suma,255);
            Checksum=dec2hex(Checksum);
            C=[A Checksum];
            fprintf(device,C);
            pause(0.07);% wait at least 50ms for serial com         
            % log if file was given
            if exist('logto','var')
                fprintf(fopen(logto,'a'), "    >ValveOpen (" + ...
                    datestr(now)+ "): opened valve #" + number  + "\r\n");
            end
        end

        % ----------------------------------------------------------------
        % Function to set the valve flow. This will update the setpoint 
        % to the given value, the valve will try to output this flow 
        % while in control mode. If ValveOpen or ValveClose were used, 
        % set point will not work until control mode is activated again 
        % with ValveControl
        function C=ValveSetFlow(app, number,Setpoint,device, logto)
            if number<10
                A=['@0' ,int2str(number)];
            else
                A=['@' ,int2str(number)];
            end
            if Setpoint<0
                 disp('Flow Value cannot be negative')
            end
            if Setpoint<10
                A=[A, 'WSV000',int2str(Setpoint)];
            elseif Setpoint<100
                A=[A, 'WSV00',int2str(Setpoint)];
            elseif Setpoint<1000
                A=[A, 'WSV0',int2str(Setpoint)];
             else 
                A=[A, 'WSV',int2str(Setpoint)];
            end
            if Setpoint>=0
                hexStr = compose("%X",A);
                Suma=hex2dec(hexStr);
                Suma=sum(Suma);
                Checksum=bitand(Suma,255);
                Checksum=dec2hex(Checksum);
                C=[A Checksum];
                fprintf(device,C);
            end
            pause(0.07);% wait at least 50ms for serial com
            % log if file was given
            if exist('logto','var')
                fprintf(fopen(logto,'a'), "    >ValveSetFlow (" + ...
                    datestr(now)+ "): valve #" + number + ...
                    ", set flow to " + Setpoint + "\r\n");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Kofloc Controllers GUI
            % Author: Sebastian Godoy-Gutierrez 
            % Contact: sebagodoy@udec.cl sebadgogu@gmail.com
            % Version: 2.1 (verss variable)
            %          (*.d if dry-run ver. without serial connection)
            % Use by MIT-type licence.
            % Hardware and serial-control logic by Alberto Riquelme,
            % Msc in electrical engineering (alberto.rikelme@gmail.com).
            % App created to program mass flow controlers for the 
            % CarboCat laboratory, University of Concepcion.
            % Please check carefully the correct working of any
            % functionallity that you intend to use before trusting this
            % app for long run experiments. Happy sciencing!

            clear all
            close all
            clc
            disp("Starting from a clean environment")
            global runstatus runtime maxtime PrevSteps verss
            verss = 2.1;
            app.ARSAGG0423V21Label = "AR - SAGG-04/23 - V" + ...
                num2str(verss);
            runstatus = "Setup";
            maxtime = 0;
            PrevSteps = [];
            disp("Kofloc Controller - V"+num2str(verss))
        end

        % Button pushed function: EndrunButton
        function EndrunButtonPushed(app, event)
            % Update buttons
            global maxtime runstatus thissteptime NextSteps
            runstatus = 'Cancelled';
            app.StartButton.Enable = true;
            app.EndrunButton.Enable = false
            app.txt_time_end.Text = datestr(now);
            app.txt_status.Text = runstatus;
            app.running_lamp.Color= [1.,0.,0.];          
            % end conditions
            thissteptime = 0;
            NextSteps = [];
            % report end
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir,'a'), ...
                "\r\n" + " <!!> Run cancelled (" + datestr(now) + ...
                ")\r\n");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global maxtime runtime steptime runstatus NextSteps device thissteptime paused_time_log nvalves_status nvalves_sp PrevSteps verss
            runstatus = 'Running';
            disp(" Run started at " + datestr(now));
            % Update buttons
            app.TabGroupLeft.SelectedTab = app.tb_runtime;
            app.StartButton.Enable = false;
            app.OpenButton.Enable = false;
            app.btn_connect.Enable = false;
            app.btn_disconnect.Enable = false;
            app.EndrunButton.Enable = true;
            app.btn_pause.Enable = true;
            % Text and indicators
            paused_time_log = 0;
            app.txt_time_start.Text = datestr(now);
            app.txt_time_end.Text = "(estim.) " + datestr(now+maxtime/(24*60*60));
            app.txt_time_paused.Text = char(duration([0.,0.,paused_time_log]));
            app.txt_status.Text = runstatus;
            app.running_lamp.Color= [0.,1.,0.];
            % logfile
            app.txt_logfile_name.Enable = false;

            % Start log
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir,'a'), ...
                "\r\n" + " >>>>>> Starting run (" + ...
                datestr(now) + ")\r\n\r\n");

            % -------------------------------------------------------------
            % ---- Start run loop
            %maxtime = 10
            runtime = tic;
            steptime = tic;
            thissteptime = 0;
            stepcounter = 0;
            
            % while toc(runtime) <= maxtime+1
            while size(NextSteps) >= 1
%                 disp(" ------------------------------------------------- ")
%                 disp("Next steps : ")
%                 disp(size(NextSteps))
%                 disp(NextSteps)
                % ---- Advance Step
                % if there is no time step
                if toc(steptime) > thissteptime
                    % ------------------------------------------------------------
                    % disable ending and editing steps
                    app.btn_EndCurrentStep.Enable = false;
                    app.btn_NextStepEdit.Enable = false;
                    % move current to prev
                    PrevSteps = [PrevSteps ; ...
                        "[" + string(stepcounter) + "]" + ...
                        " (" + datestr(now())+ ") " + ...
                        app.ta_CurrentStep.Value];
                    app.ta_PrevSteps.Value = sprintf(strjoin(PrevSteps,"\n"));
                    % move first-next to current
                    app.ta_CurrentStep.Value = NextSteps{1};
                    % remove first-next and update
                    NextSteps = NextSteps(2:end);
                    app.ta_NextSteps.Value = sprintf(strjoin(NextSteps(1:end-1),"\n"));
                    % advance counter
                    stepcounter = stepcounter + 1;

                    % ------------------------------------------------------------
                    % execute current step
                    % flow
                    if contains(upper(app.ta_CurrentStep.Value),"FLOW")
                        commands = split(app.ta_CurrentStep.Value,',');
                        vnumber = str2num(extractAfter(commands{1}, ":"));
                        vflow = str2num(extractAfter(commands{2}, "="));
                        ValveSetFlow(app, vnumber, vflow, device, tmp_logfiledir);
                        nvalves_sp(vnumber) = vflow;
                    % open
                    elseif contains(upper(app.ta_CurrentStep.Value),"OPEN")
                        commands = split(app.ta_CurrentStep.Value,',');
                        vnumber = str2num(extractAfter(commands{1}, ":"));
                        ValveOpen(app, vnumber, device, tmp_logfiledir);
                        nvalves_status(vnumber) = "Open";
                    % close
                    elseif contains(upper(app.ta_CurrentStep.Value),"CLOSE")
                        commands = split(app.ta_CurrentStep.Value,',');
                        vnumber = str2num(extractAfter(commands{1}, ":"));
                        ValveClose(app, vnumber, device, tmp_logfiledir);
                        nvalves_status(vnumber) = "Close"
                    % add waiting step
                    elseif contains(upper(app.ta_CurrentStep.Value),"DT")
                        steptime = tic;
                        dtt = extractAfter(app.ta_CurrentStep.Value,'=');
                        thissteptime = str2num(dtt{1})*60;
                        % report
                        fprintf(fopen(tmp_logfiledir,'a'), ...
                            "  <t> Wait time   (" + datestr(now)+ "):" + ...
                            " set to wait for " + string(str2num(dtt{1})) + ...
                            " min." + "\r\n");
                        % enable ending and edit steps
                        app.btn_EndCurrentStep.Enable = true;
                        app.btn_NextStepEdit.Enable = true;
                    end
                    % -----------------------------------------------------------
                    
                    
                end 

                % ---- message: total time
                elapsedtime = toc(runtime);
                app.txt_time_elapsed.Text = ...
                    string(duration([0.,0.,elapsedtime])) + ...
                    " / " + string(duration([0.,0.,maxtime])) + ...
                    sprintf(' (%0.1f%%) ',(elapsedtime*100)/maxtime);
                app.gauge_time.Value = (elapsedtime*100)/maxtime;

                % ---- message: step time
                elapsedsteptime = toc(steptime);
                app.txt_time_elapsed_step.Text = ...
                    string(duration([0.,0.,elapsedsteptime])) + ...
                    " / " + string(duration([0.,0.,thissteptime])) + ...
                    sprintf(' (%0.1f%%) ',(elapsedsteptime*100)/thissteptime);


                % ---- wait 1s for next step
                % dynamic pause, considers parsing time
                pause(1-(toc(runtime)-fix(toc(runtime))));
            
            end

            % -----------------------------------------------------------
            % ---- Normal end run
            fprintf(fopen(tmp_logfiledir,'a'),"\r\n" + ...
                " >>>> Ending run (" + datestr(now) + ")\r\n\r\n");

            % Restitute app form
            app.btn_EndCurrentStep.Enable = false;
            app.EndrunButton.Enable = false;
            app.btn_pause.Enable = false;
            maxtime = 0;
            thissteptime = 0;
            NextSteps = [];

            app.txt_time_end.Text = datestr(now);

            % set valves control mode flow zero
            nvalves = split(app.txt_valves.Value,',');
            for i=1:size(nvalves)
                % Control mode
                ValveClose(app, str2num(nvalves{i}), device, tmp_logfiledir);
                % wait 1 sec to give it time to close, just in case
                pause(1) 
            end
            % Ending log file
            fprintf(fopen(tmp_logfiledir,'a'), ...
                "\r\n" + "Run ended at " + ...
                datestr(now) + "." + "\r\n\r\n");
            % Add division for PrevSteps
            PrevSteps = [PrevSteps ; " ------------------------" + ...
                        "------------"];

            % End window
            uiwait(msgbox([' Run ended after ', ...
                char(duration([0.,0.,toc(runtime)]))], 'Hey!'));

            app.running_lamp.Color = [.65, .65, .65];
            app.StartButton.Enable = true;
            app.btn_connect.Enable = false;
            app.btn_disconnect.Enable = true;
            app.txt_status.Text = 'Setup';
            % app.txt_valves.Enable = true;
            % app.txa_port.Enable = true;
            % app.na_baud.Enable = true;
            app.txt_runname.Enable = true;
            % app.OpenButton.Enable = true;
            app.ta_CurrentStep.Value = "Starting run step";
            app.txt_scriptfile_name.Enable = true;
            app.txt_logfile_name.Enable = true;
            app.btn_NextStepEdit.Enable = true;
            disp(" Run ended succesfuly at " + datestr(now));


        end

        % Button pushed function: btn_pause
        function btn_pauseButtonPushed(app, event)
            %prepare
            global maxtime thissteptime paused_time_log runstatus runtime
            pausetime = tic;
            app.running_lamp.Color = [1., 1., 0.];
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            runstatus = 'Paused';
            app.txt_status.Text = runstatus;
            % report starting pause
            fprintf(fopen(tmp_logfiledir,'a'), ...
                "  <!> Paused from " + datestr(now));
            % actual pause
            uiwait(msgbox({"Paused. No changes to the controllers " + ...
                "will be made while this"; ...
                "window is open. Run will resume and paused time " + ...
                "will be added to the"; ...
                "runtime when this window is closed."}, ...
                "Paused run", "warn"));
            % continue and correct for paused time
            itime = toc(pausetime);
            maxtime = maxtime + itime; % total time correction
            thissteptime = thissteptime + itime; % step time correction
            paused_time_log = paused_time_log + itime; % pause time log
            app.txt_time_paused.Text = char(duration([0.,0.,paused_time_log]));
            app.txt_time_end.Text = "(estim.) " + datestr(now+(maxtime-toc(runtime))/(24*60*60));
            % report ending pause
            fprintf(fopen(tmp_logfiledir,'a'), " to " + datestr(now) + ...
                ", t=" + string(duration([0.,0.,paused_time_log])) + "\r\n");
            % status
            app.running_lamp.Color = [0., 1., 0.];
            runstatus = 'Running';
            app.txt_status.Text = runstatus;
            
        end

        % Button pushed function: btn_lognote_add
        function btn_lognote_addButtonPushed(app, event)
            % Add note to current logfile
            global runstatus runtime
            % Check there is a note
            if not(strrep(app.txa_lognote.Value," ","") == "")
                global runtime runstatus
                tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
                tmp_notetext = ['  <!> Note'];
                
                % Add date
                if app.cb_lognote_addtime.Value
                    tmp_notetext = [tmp_notetext ' (' datestr(now)];
                    if not(runstatus=="Setup")
                        tmp_notetext = [tmp_notetext ', t=' char(duration([0.,0.,toc(runtime)]))];
                    end
                    tmp_notetext = [tmp_notetext ')'];
                end                
                tmp_notetext = [tmp_notetext ' : \n'];
                
                % Add
                fprintf(fopen(tmp_logfiledir, 'a'), tmp_notetext);
                fprintf(fopen(tmp_logfiledir, 'a'), "        " + ...
                    strjoin(app.txa_lognote.Value,"\r\n        ") + ...
                    "\r\n");

                % Clear box
                app.txa_lognote.Value = "";
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Open
            app.TabGroupLeft.SelectedTab = app.tb_setup;
            scriptfilename = app.txt_scriptfile_name.Value;
            scriptfile = readlines(scriptfilename, 'EmptyLineRule','skip');
            % Parse file
            app.txt_runname.Value = scriptfile{1};
            i=1;
            while i < length(scriptfile)+1
                % Valves declaration
                if contains(upper(scriptfile{i}),"INCLUDE VALVE")
                    valves = str2num(extractAfter(scriptfile{i},':'));
                % baud
                elseif contains(upper(scriptfile{i}),"BAUD")
                    iBaud = str2num(extractAfter(scriptfile{i},'='));
                % port
                elseif contains(upper(scriptfile{i}),"PORT")
                    iPort = strip(extractAfter(scriptfile{i},'='));
                % Start block
                elseif contains(upper(scriptfile{i}),"START")
                    startline = i+1;
                % End line
                elseif contains(upper(scriptfile{i}),"END")
                    endline = i-1;
                end
                i = i+1;
            end
            
            % Create list next steps, clean and parse
            global NextSteps maxtime
            iNextSteps = scriptfile(startline:endline);
            newNextSteps = [];
            for i=1:size(iNextSteps)
                if or (contains(upper(string(iNextSteps{i})),"VALVE"), ...
                    contains(upper(string(iNextSteps{i})),"DT"))
                    newNextSteps = [newNextSteps; string(iNextSteps{i})];
                end
            end
            NextSteps = [newNextSteps; "END LINE"];

            % Summ all time
            itime = 0;
            for i=1:size(NextSteps)
                if contains(upper(NextSteps{i}), "DT")
                    itime = itime + 1 + 60*str2num(extractAfter(NextSteps{i},"="));
                elseif contains(upper(NextSteps{i}), "VALVE")
                    itime = itime + 1;
                end
            end
            maxtime = itime;
            app.txt_time_estimtotal.Text = string(duration([0.,0.,itime]));

            % Report
            app.txt_valves.Value = join(string(valves),','); 
            app.txa_port.Value = iPort;
            app.na_baud.Value = iBaud;
            app.ta_NextSteps.Value = sprintf(strjoin(NextSteps(1:end-1),"\n"));

            % Update suggested logfile
            app.txt_logfile_name.Value = extractBefore(scriptfilename,".") + "_log";
            
            % Enable start
            app.btn_connect.Enable = true;
        end

        % Button pushed function: btn_connect
        function btn_connectButtonPushed(app, event)
            % Connections
            global device nvalves_status nvalves_sp verss

            % Check if all info needed to connect is provided
            if app.txt_runname.Value == ""
                msgbox({"All information in the Setup tab is " + ...
                    "necessary to connect."; ...
                    "Please provide a name for this run (e.g. " + ...
                    "Experiment N°1: 1-2H2)"}, ...
                "Missing something to connect","warn");
                return
            elseif app.txt_valves.Value == ""
                msgbox({"All information in the Setup tab is " + ...
                    "necessary to connect."; ...
                    "Please specify the controller ID numbers (e.g. " + ...
                    "1,2,3)"}, ...
                "Missing something to connect","warn");
                return
            elseif app.txa_port.Value == ""
                msgbox({"All information in the Setup tab is " + ...
                    "necessary to connect."; ...
                    "Please specify the serial port (e.g. COM8, " + ...
                    "check ? for help finding the port)"}, ...
                "Missing something to connect","warn");
                return
            end


            % log part
            disp(app.txt_runname.Value)

            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir, 'a'), "Kofloc Controller -V"+num2str(verss));
            fprintf(fopen(tmp_logfiledir, 'a'), string(join(repmat({'*'},1,80),''))+"\r\n");
            fprintf(fopen(tmp_logfiledir, 'a'), "  " + app.txt_runname.Value + "\r\n");
            fprintf(fopen(tmp_logfiledir, 'a'), string(join(repmat({'*'},1,80),''))+"\r\n");


            % connect part
            SerialPort=app.txa_port.Value;
            SerialBaud=app.na_baud.Value;
            ClosePort(app, SerialPort); % Close first just in case
            device=OpenPort(app, SerialPort,SerialBaud, [app.txt_logfile_name.Value '.txt']);
            
            % set valves control mode flow zero
            nvalves = split(app.txt_valves.Value,',');
            nvalves_status = [];
            nvalves_sp = [];
            for i=1:size(nvalves)
                % Control mode
                ValveControl(app, str2num(nvalves{i}), device, tmp_logfiledir);
                % Set flow to zero
                ValveSetFlow(app, str2num(nvalves{i}), 0., device, tmp_logfiledir);
                % save status and set-point
                nvalves_status = [nvalves_status "Control"];
                nvalves_sp = [nvalves_sp 0.];
            end
            
            % report
            app.txt_logfile_name.Enable = false;
            app.txt_scriptfile_name.Enable = false;
            app.OpenButton.Enable = false;
            app.btn_connect.Enable = false;
            app.txa_port.Enable = false;
            app.na_baud.Enable = false;
            app.txt_valves.Enable = false;
            app.txt_runname.Enable = false;
            app.StartButton.Enable = true;
            app.btn_NextStepEdit.Enable = true;
            app.ConnectedLamp.Color = [0., 1., 0.];
            app.ValveNumberDropDown.Items = nvalves;
            app.btn_disconnect.Enable = true;
            % enable manual control
            app.ValveNumberDropDown.Enable = true;
            app.ValveCheckButton.Enable = true;
            app.ManualClose.Enable = true;
            app.ManualOpen.Enable = true;
            app.ManualControl.Enable = true;
            app.ManualSetFlow.Enable = true;
            app.ManualFlowNumber.Enable = true;
            
        end

        % Button pushed function: btn_EndCurrentStep
        function btn_EndCurrentStepButtonPushed(app, event)
            global thissteptime maxtime steptime runtime
            % get time difference that is being jumped
            missingtime = thissteptime - toc(steptime);
            % adjust maxtime
            maxtime = maxtime - missingtime;
            % adjust estimated end time
            app.txt_time_end.Text = "(estim.) " + datestr(now+(maxtime-toc(runtime))/(24*60*60));
            % now jump, this step limit is zero
            thissteptime = 0;
            % report jump
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            fprintf(fopen(tmp_logfiledir,'a'),"  <!> Jumping step (" + ...
                datestr(now) + ") : current step ended prematurely by " + ...
                "\n      user request after " + ...
                string(duration([0.,0.,toc(steptime)])) + "\r\n");

        end

        % Button pushed function: btn_NextStepEdit
        function btn_NextStepEditButtonPushed(app, event)
            % Edit next steps
            app.ta_NextSteps.Enable = true;
            app.btn_NextStepLoad.Enable = true;
            app.btn_EndCurrentStep.Enable = false;
            app.btn_NextStepEdit.Enable = false;
        end

        % Button pushed function: btn_NextStepLoad
        function btn_NextStepLoadButtonPushed(app, event)
            % Load edited changes to next steps area
            global NextSteps maxtime runtime runstatus
            app.ta_NextSteps.Enable = false;
            app.btn_NextStepLoad.Enable = false;
            app.btn_NextStepEdit.Enable = true;
            tmp_logfiledir = [app.txt_logfile_name.Value, '.txt'];
            if runstatus == "Running"
                app.btn_EndCurrentStep.Enable = true;
            end

            % disp for test
%             disp("Next steps:")
%             disp(NextSteps)
%             disp("textbox:")
%             disp(app.ta_NextSteps.Value)

            % get previous time in NextSteps
            itime = 0;
            for i=1:size(NextSteps)
                if contains(upper(NextSteps{i}), "DT")
                    itime = itime + 1 + 60*str2num(extractAfter(NextSteps{i},"="));
                elseif contains(upper(NextSteps{i}), "VALVE")
                    itime = itime + 1;
                end
            end


            % parse new NextSteps
            newNextSteps = [];
            for i=1:size(app.ta_NextSteps.Value)
                if or (contains(upper(string(app.ta_NextSteps.Value{i})),"VALVE"), ...
                    contains(upper(string(app.ta_NextSteps.Value{i})),"DT"))
                    newNextSteps = [newNextSteps; string(app.ta_NextSteps.Value{i})];
                end
            end
            NextSteps = [newNextSteps; "END LINE"];
            app.ta_NextSteps.Value = sprintf(strjoin(NextSteps(1:end-1),"\n"));
%             disp("New next:")
%             disp(newNextSteps)

            % get new time in NextSteps
            ntime = 0;
            for i=1:size(NextSteps)
                if contains(upper(NextSteps{i}), "DT")
                    ntime = ntime + 1 + 60*str2num(extractAfter(NextSteps{i},"="));
                elseif contains(upper(NextSteps{i}), "VALVE")
                    ntime = ntime + 1;
                end
            end
            % correct time
            maxtime = maxtime - (itime - ntime);
            if runstatus == "Setup"
                app.txt_time_end.Text = "(estim.) " + datestr(now+maxtime/(24*60*60));
            else
                app.txt_time_end.Text = "(estim.) " + datestr(now+(maxtime-toc(runtime))/(24*60*60));
            end

            app.txt_time_estimtotal.Text = string(duration([0.,0.,maxtime]));
            % report
            fprintf(fopen(tmp_logfiledir,'a'), ...
                "  <!> Modified the list of the next steps (" + datestr(now) + "), new " + ...
                "\n      left time is " + string(duration([0.,0.,ntime])));
            if not(runstatus == "Setup")
                fprintf(fopen(tmp_logfiledir,'a'), ...
                    ", estimated to end at " + ...
                    datestr(now+(maxtime-toc(runtime))/(24*60*60))+ "\r\n");
            else
                fprintf(fopen(tmp_logfiledir,'a'), "\r\n");
            end


        end

        % Button pushed function: btn_disconnect
        function btn_disconnectButtonPushed(app, event)
            % Enables new connection while preventing start
            % Butons change
            app.StartButton.Enable = false;
            app.btn_disconnect.Enable = false;

            app.OpenButton.Enable = true;
            app.txt_logfile_name.Enable = true;
            app.txt_scriptfile_name.Enable = true;
            app.btn_connect.Enable = true;

            app.txt_runname.Enable = true;
            app.txa_port.Enable = true;
            app.na_baud.Enable = true;
            app.txt_valves.Enable = true;
            
            app.ConnectedLamp.Color = [0.65, 0.65, 0.65];
            
            % enable manual control
            app.ValveNumberDropDown.Enable = false;
            app.ValveCheckButton.Enable = false;
            app.ManualClose.Enable = false;
            app.ManualOpen.Enable = false;
            app.ManualControl.Enable = false;
            app.ManualSetFlow.Enable = false;
            app.ManualFlowNumber.Enable = false;

        end

        % Button pushed function: ManualExplainModes
        function ManualExplainModesButtonPushed(app, event)
            msgbox({"The valves operate in one of three modes: " + ...
                "Open, Close and Control"; ...
                "Open and Close do exactly what they say, and " + ...
                "overwrite whatever the set point is."; ...
                "In Control mode the flow is regulated to the " + ...
                "active set point. The set point can be changed " + ...
                "in Open or Close modes but it will not affect " + ...
                "the mass flow until the valve is set to " + ...
                "Control mode again" + ...
                "Normal operation requires Control mode."}, ...
                "Valve operation modes","help")
        end

        % Button pushed function: ManualOpen
        function ManualOpenButtonPushed(app, event)
            % Manual operation: Open
            global device nvalves_status PrevSteps
            fprintf(fopen([app.txt_logfile_name.Value, '.txt'],'a'), ...
                "  <m>Manual Operation, set to Open mode" + "\r\n");
            ValveOpen(app, str2num(app.ValveNumberDropDown.Value), ...
                device, [app.txt_logfile_name.Value, '.txt']);
            nvalves_status(str2num(app.ValveNumberDropDown.Value)) = "Open";
            % Report - warn
            msgbox({"Note that this changed the operation mode of " + ...
                "controller #" + string(app.ValveNumberDropDown.Value) ; ...
                " to Open. Controller is now open, its setpoint will be "; ...
                "ignored until control mode is active (use the " + ...
                "check button)."}, ...
                "Controller mode changed","warn")
            % Add to list of moves
            PrevSteps = [PrevSteps ; "[m]" + " (" + datestr(now())+ ") " + ...
                        "Controller #" + ...
                        str2num(app.ValveNumberDropDown.Value) + ...
                        " Open"];
            app.ta_PrevSteps.Value = sprintf(strjoin(PrevSteps,"\n"));

        end

        % Button pushed function: ManualClose
        function ManualCloseButtonPushed(app, event)
            % Manual operation: Close
            global device nvalves_status PrevSteps
            fprintf(fopen([app.txt_logfile_name.Value, '.txt'],'a'), ...
                "  <m>Manual Operation, set to Close mode" + "\r\n");
            ValveClose(app, str2num(app.ValveNumberDropDown.Value), ...
                device, [app.txt_logfile_name.Value, '.txt']);
            nvalves_status(str2num(app.ValveNumberDropDown.Value)) = "Closed";
            % Report - warn
            msgbox({"Note that this changed the operation mode of " + ...
                "controller #" + string(app.ValveNumberDropDown.Value) ; ...
                "to Close. Controller is now open, its setpoint will be "; ...
                "ignored until control mode is active (use the " + ...
                "check button)."}, ...
                "Controller mode changed","warn")
            % Add to list of moves
            PrevSteps = [PrevSteps ; "[m]" + " (" + datestr(now())+ ") " + ...
                        "Controller #" + ...
                        str2num(app.ValveNumberDropDown.Value) + ...
                        " Close"];
            app.ta_PrevSteps.Value = sprintf(strjoin(PrevSteps,"\n"));


        end

        % Button pushed function: ManualControl
        function ManualControlButtonPushed(app, event)
            % Manual operation: Control
            global device nvalves_status PrevSteps
            fprintf(fopen([app.txt_logfile_name.Value, '.txt'],'a'), ...
                "  <m>Manual Operation, set to Control mode" + "\r\n");
            ValveControl(app, str2num(app.ValveNumberDropDown.Value), ...
                device, [app.txt_logfile_name.Value, '.txt']);
            nvalves_status(str2num(app.ValveNumberDropDown.Value)) = "Control";
            % Add to list of moves
            PrevSteps = [PrevSteps ; "[m]" + " (" + datestr(now())+ ") " + ...
                        "Controller #" + ...
                        str2num(app.ValveNumberDropDown.Value) + ...
                        " Control"];
            app.ta_PrevSteps.Value = sprintf(strjoin(PrevSteps,"\n"));

        end

        % Button pushed function: ManualSetFlow
        function ManualSetFlowButtonPushed(app, event)
            % Manual operation: Control
            global device nvalves_sp PrevSteps
            fprintf(fopen([app.txt_logfile_name.Value, '.txt'],'a'), ...
                "  <m>Manual Operation, new flow setpoint" + "\r\n");
            ValveSetFlow(app, str2num(app.ValveNumberDropDown.Value), ...
                app.ManualFlowNumber.Value, ...
                device, [app.txt_logfile_name.Value, '.txt']);  
            nvalves_sp(str2num(app.ValveNumberDropDown.Value)) = string(app.ManualFlowNumber.Value);
            % Add to list of moves
            PrevSteps = [PrevSteps ; "[m]" + " (" + datestr(now())+ ") " + ...
                        "Controller #" + ...
                        str2num(app.ValveNumberDropDown.Value) + ...
                        " flow:" + string(app.ManualFlowNumber.Value)];
            app.ta_PrevSteps.Value = sprintf(strjoin(PrevSteps,"\n"));

        end

        % Button pushed function: ValveCheckButton
        function ValveCheckButtonPushed(app, event)
            % Check valve status label
            global nvalves_status nvalves_sp
            app.ValveStatusLabel.Text = nvalves_status(str2num(app.ValveNumberDropDown.Value));
            app.ValveSPLabel.Text = string(nvalves_sp(str2num(app.ValveNumberDropDown.Value)));
            app.lastchecktimeLabel.Text = split(datestr(now()), " ");

        end

        % Button pushed function: ManualExplainModes_2
        function ManualExplainModes_2ButtonPushed(app, event)
            % Notes on the connection
            msgbox({"Connection via USB to an RS232-to-USB conversor " + ...
                "where all the controllers are connected as a " + ...
                "serial port."; ...
                "To manually find the USB port where the controllers "; ...
                "are connected type instrfind in the matlab " + ...
                "command window after and before plugging the " + ...
                "serial connector of the controllers, the " + ...
                "should hint where it is connected, usually some " + ...
                "COMx (x=number) port."; ...
                "Baud rate is usually 9600."}, ...
                "Controller port connection help","help")
        end

        % Button pushed function: btn_Version
        function btn_VersionButtonPushed(app, event)
            global verss
            % Notes on the connection
            msgbox({"Koflock Controller - V" + num2str(verss); ...
                "This application helps to schedulle " + ...
                "sequences for mass flow controllers Kofloc 8500" ; ...
                "", ; ...
                "Hardware and serial port control by " + ...
                "Alberto Riquelme, Msc in electrical engineering " + ...
                "(alberto.rikelme@gmail.com), " + ...
                "GUI by Sebastian Godoy-Gutierrez, " + ...
                "PhD Chemical Engineering (sebagodoy@udec.cl). " + ...
                "Most updated version of the app available under " + ...
                "MIT licence in the repository" ; ...
                "github.com/sebagodoy/LabAutomatization" ; "" ; ...
                "Check the correct function of all the " + ...
                "functionalities before log runs."}, ...
                "App information","help")
        end

        % Button pushed function: btn_OpenHelp
        function btn_OpenHelpButtonPushed(app, event)
            % Help message for open option
            msgbox({"A script file containing the setup information " + ...
                "and step sequences can be loaded." ; ...
                "A basic format may be:", ; ...
                "    Experiment tittle, conditions, N°1" ; ...
                "    include valves: 1, 2, 3" ; ...
                "    SerialPort = COM5" ; ...
                "    SerialBaud = 9600" ; ...
                "    start" ; ...
                "    valve:1 , flow = 110"; ...
                "    valve:2 , flow = 220"; ...
                "    dt = 5"
                "    valve:1 , flow = 110"; ...
                "    valve:2 , flow = 220"; ...
                "    dt = 5"
                "    end" ; ...
                "Steps in sequence between start and end tags, " + ...
                "line format is relevant (but not caps):" ; ...
                "''dt=x'' introduces a waiting step of x minutes, " + ...
                "''valve:x'', indicates a change for the " + ...
                "controller x, ''flow=y'' tag changes the flow " + ...
                "setpoint. ''Open'' and ''Close'' tags are " + ...
                "available but these change the controller mode and the " + ...
                "setpoint will be ignored until the x controller " + ...
                "is set again to ''Control''."
                }, ...
                "App information","help")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {370, 370};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {374, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EndrunButton = uibutton(app.LeftPanel, 'push');
            app.EndrunButton.ButtonPushedFcn = createCallbackFcn(app, @EndrunButtonPushed, true);
            app.EndrunButton.Enable = 'off';
            app.EndrunButton.Text = 'End run';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.running_lamp = uilamp(app.LeftPanel);
            app.StartLabel_3 = uilabel(app.LeftPanel);
            app.StartLabel_3.Text = 'Log file name';
            app.txt_logfile_name = uieditfield(app.LeftPanel, 'text');
            app.txt_logfile_name.Value = 'logfile';
            app.StartLabel_4 = uilabel(app.LeftPanel);
            app.StartLabel_4.Text = 'Script file name';
            app.txt_scriptfile_name = uieditfield(app.LeftPanel, 'text');
            app.txt_scriptfile_name.Value = 'script.txt';
            app.btn_pause = uibutton(app.LeftPanel, 'push');
            app.btn_pause.ButtonPushedFcn = createCallbackFcn(app, @btn_pauseButtonPushed, true);
            app.btn_pause.Enable = 'off';
            app.btn_pause.Text = 'Pause';
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.txt_status = uilabel(app.LeftPanel);
            app.txt_status.Text = 'Setup';
            app.TabGroupLeft = uitabgroup(app.LeftPanel);
            app.tb_setup = uitab(app.TabGroupLeft);
            app.tb_setup.Title = 'Setup';
            app.btn_connect = uibutton(app.tb_setup, 'push');
            app.btn_connect.ButtonPushedFcn = createCallbackFcn(app, @btn_connectButtonPushed, true);
            app.btn_connect.Text = 'Connect & log';
            app.PortLabel = uilabel(app.tb_setup);
            app.PortLabel.Text = 'Port :';
            app.txa_port = uieditfield(app.tb_setup, 'text');
            app.BaudrateLabel = uilabel(app.tb_setup);
            app.BaudrateLabel.Text = 'Baud rate :';
            app.na_baud = uieditfield(app.tb_setup, 'numeric');
            app.na_baud.Value = 9600;
            app.txt_time_estimtotal = uilabel(app.tb_setup);
            app.txt_time_estimtotal.Text = '';
            app.EstimatedtotaltimeLabel = uilabel(app.tb_setup);
            app.EstimatedtotaltimeLabel.Text = 'Estimated total time : ';
            app.RunnameLabel = uilabel(app.tb_setup);
            app.RunnameLabel.Text = 'Run name :';
            app.txt_runname = uieditfield(app.tb_setup, 'text');
            app.ConnectedLampLabel = uilabel(app.tb_setup);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.tb_setup);
            app.ValuesLabel = uilabel(app.tb_setup);
            app.ValuesLabel.Text = 'Controllers :';
            app.txt_valves = uieditfield(app.tb_setup, 'text');
            app.btn_disconnect = uibutton(app.tb_setup, 'push');
            app.btn_disconnect.ButtonPushedFcn = createCallbackFcn(app, @btn_disconnectButtonPushed, true);
            app.btn_disconnect.Enable = 'off';
            app.btn_disconnect.Text = 'Edit connection';
            app.ManualExplainModes_2 = uibutton(app.tb_setup, 'push');
            app.ManualExplainModes_2.ButtonPushedFcn = createCallbackFcn(app, @ManualExplainModes_2ButtonPushed, true);
            app.ManualExplainModes_2.Text = '?';
            app.ManualcontrolTab = uitab(app.TabGroupLeft);
            app.ManualcontrolTab.Title = 'Manual control';
            app.ValvenumberDropDownLabel = uilabel(app.ManualcontrolTab);
            app.ValvenumberDropDownLabel.Text = 'Valve number';
            app.ValveNumberDropDown = uidropdown(app.ManualcontrolTab);
            app.ValveNumberDropDown.Items = {'Connect first'};
            app.ValveNumberDropDown.Enable = 'off';
            app.ValveNumberDropDown.Value = 'Connect first';
            app.ManualOpen = uibutton(app.ManualcontrolTab, 'push');
            app.ManualOpen.ButtonPushedFcn = createCallbackFcn(app, @ManualOpenButtonPushed, true);
            app.ManualOpen.Enable = 'off';
            app.ManualOpen.Text = 'Open';
            app.ManualClose = uibutton(app.ManualcontrolTab, 'push');
            app.ManualClose.ButtonPushedFcn = createCallbackFcn(app, @ManualCloseButtonPushed, true);
            app.ManualClose.Enable = 'off';
            app.ManualClose.Text = 'Close';
            app.NewflowsetpointEditFieldLabel = uilabel(app.ManualcontrolTab);
            app.NewflowsetpointEditFieldLabel.Text = 'New flow setpoint : ';
            app.ManualFlowNumber = uieditfield(app.ManualcontrolTab, 'numeric');
            app.ManualFlowNumber.Enable = 'off';
            app.ManualSetFlow = uibutton(app.ManualcontrolTab, 'push');
            app.ManualSetFlow.ButtonPushedFcn = createCallbackFcn(app, @ManualSetFlowButtonPushed, true);
            app.ManualSetFlow.Enable = 'off';
            app.ManualSetFlow.Text = 'Set';
            app.ManualControl = uibutton(app.ManualcontrolTab, 'push');
            app.ManualControl.ButtonPushedFcn = createCallbackFcn(app, @ManualControlButtonPushed, true);
            app.ManualControl.Enable = 'off';
            app.ManualControl.Text = 'Control';
            app.OperationmodeLabel = uilabel(app.ManualcontrolTab);
            app.OperationmodeLabel.Text = 'Operation mode';
            app.OperationincontrolmodeLabel = uilabel(app.ManualcontrolTab);
            app.OperationincontrolmodeLabel.Text = 'Operation in control mode';
            app.ManualExplainModes = uibutton(app.ManualcontrolTab, 'push');
            app.ManualExplainModes.ButtonPushedFcn = createCallbackFcn(app, @ManualExplainModesButtonPushed, true);
            app.ManualExplainModes.Text = '?';
            app.StatusLabel = uilabel(app.ManualcontrolTab);
            app.StatusLabel.Text = 'Status : ';
            app.ValveStatusLabel = uilabel(app.ManualcontrolTab);
            app.ValveStatusLabel.Text = 'Valve-Status';
            app.ValveCheckButton = uibutton(app.ManualcontrolTab, 'push');
            app.ValveCheckButton.ButtonPushedFcn = createCallbackFcn(app, @ValveCheckButtonPushed, true);
            app.ValveCheckButton.Enable = 'off';
            app.ValveCheckButton.Text = 'Check';
            app.SetPointLabel = uilabel(app.ManualcontrolTab);
            app.SetPointLabel.Text = 'Set Point : ';
            app.ValveSPLabel = uilabel(app.ManualcontrolTab);
            app.ValveSPLabel.Text = 'Valve-SP';
            app.lastcheckedLabel = uilabel(app.ManualcontrolTab);
            app.lastcheckedLabel.Text = 'last checked:';
            app.lastchecktimeLabel = uilabel(app.ManualcontrolTab);
            app.lastchecktimeLabel.Text = 'last-check-time';
            app.tb_runtime = uitab(app.TabGroupLeft);
            app.tb_runtime.Title = 'Run time information';
            app.StarttimeLabel = uilabel(app.tb_runtime);
            app.StarttimeLabel.Text = 'Start time : ';
            app.txt_time_start = uilabel(app.tb_runtime);
            app.txt_time_start.Text = {''; ''};
            app.txt_time_end = uilabel(app.tb_runtime);
            app.txt_time_end.Text = '';
            app.EndtimeLabel = uilabel(app.tb_runtime);
            app.EndtimeLabel.Text = 'End time : ';
            app.txt_time_paused = uilabel(app.tb_runtime);
            app.txt_time_paused.Text = '';
            app.PausedtimeLabel = uilabel(app.tb_runtime);
            app.PausedtimeLabel.Text = 'Paused time : ';
            app.txt_time_elapsed = uilabel(app.tb_runtime);
            app.txt_time_elapsed.Text = '';
            app.totalLabel = uilabel(app.tb_runtime);
            app.totalLabel.Text = 'total : ';
            app.gauge_time = uigauge(app.tb_runtime, 'linear');
            app.gauge_time.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.txt_time_elapsed_step = uilabel(app.tb_runtime);
            app.txt_time_elapsed_step.Text = '';
            app.ElapsedtimestepLabel = uilabel(app.tb_runtime);
            app.ElapsedtimestepLabel.Text = 'Elapsed time step : ';
            app.KoflocControllerGUILabel = uilabel(app.LeftPanel);
            app.KoflocControllerGUILabel.Text = 'Kofloc Controller GUI';
            app.btn_OpenHelp = uibutton(app.LeftPanel, 'push');
            app.btn_OpenHelp.ButtonPushedFcn = createCallbackFcn(app, @btn_OpenHelpButtonPushed, true);
            app.btn_OpenHelp.Text = '?';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.next_steps = uitab(app.TabGroup);
            app.next_steps.Title = 'Steps';
            app.btn_NextStepEdit = uibutton(app.next_steps, 'push');
            app.btn_NextStepEdit.ButtonPushedFcn = createCallbackFcn(app, @btn_NextStepEditButtonPushed, true);
            app.btn_NextStepEdit.Text = 'Edit';
            app.ta_NextSteps = uitextarea(app.next_steps);
            app.ta_NextSteps.Enable = 'off';
            app.btn_EndCurrentStep = uibutton(app.next_steps, 'push');
            app.btn_EndCurrentStep.ButtonPushedFcn = createCallbackFcn(app, @btn_EndCurrentStepButtonPushed, true);
            app.btn_EndCurrentStep.Enable = 'off';
            app.btn_EndCurrentStep.Text = 'End Current step';
            app.ta_CurrentStep = uitextarea(app.next_steps);
            app.ta_CurrentStep.Editable = 'off';
            app.ta_CurrentStep.Enable = 'off';
            app.ta_CurrentStep.Value = {'Starting run step'};
            app.CurrentLabel = uilabel(app.next_steps);
            app.CurrentLabel.Text = 'Current : ';
            app.NextLabel = uilabel(app.next_steps);
            app.NextLabel.Enable = 'off';
            app.NextLabel.Text = 'Next : ';
            app.btn_NextStepLoad = uibutton(app.next_steps, 'push');
            app.btn_NextStepLoad.ButtonPushedFcn = createCallbackFcn(app, @btn_NextStepLoadButtonPushed, true);
            app.btn_NextStepLoad.Enable = 'off';
            app.btn_NextStepLoad.Text = 'Load';
            app.prev_steps = uitab(app.TabGroup);
            app.prev_steps.Title = 'Previous steps';
            app.ta_PrevSteps = uitextarea(app.prev_steps);
            app.ta_PrevSteps.Editable = 'off';
            app.ta_PrevSteps.Enable = 'off';
            app.CheckthedetailsandmanualchangesnotshownhereinthelogfileLabel = uilabel(app.prev_steps);
            app.CheckthedetailsandmanualchangesnotshownhereinthelogfileLabel.Text = '*Check the details, manual changes and log notes in the log file';
            app.AddlognotesTab = uitab(app.TabGroup);
            app.AddlognotesTab.Title = 'Add log notes';
            app.CommentTextAreaLabel = uilabel(app.AddlognotesTab);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.txa_lognote = uitextarea(app.AddlognotesTab);
            app.cb_lognote_addtime = uicheckbox(app.AddlognotesTab);
            app.cb_lognote_addtime.Text = 'Add time';
            app.cb_lognote_addtime.Value = true;
            app.btn_lognote_add = uibutton(app.AddlognotesTab, 'push');
            app.btn_lognote_add.ButtonPushedFcn = createCallbackFcn(app, @btn_lognote_addButtonPushed, true);
            app.btn_lognote_add.Text = 'add';
            app.ARSAGG0423V21Label = uilabel(app.RightPanel);
            app.ARSAGG0423V21Label.Text = 'AR - SAGG-04/23 - V2.1';
            app.btn_Version = uibutton(app.RightPanel, 'push');
            app.btn_Version.ButtonPushedFcn = createCallbackFcn(app, @btn_VersionButtonPushed, true);
            app.btn_Version.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end